classdef covid_plotter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        CountryListBoxLabel        matlab.ui.control.Label
        CountryListBox             matlab.ui.control.ListBox
        AveragedofdaysSliderLabel  matlab.ui.control.Label
        AveragedofdaysSlider       matlab.ui.control.Slider
        DatatoPlotButtonGroup      matlab.ui.container.ButtonGroup
        CasesButton                matlab.ui.control.RadioButton
        DeathsButton               matlab.ui.control.RadioButton
        BothButton                 matlab.ui.control.RadioButton
        OptionButtonGroup          matlab.ui.container.ButtonGroup
        CumulativeButton           matlab.ui.control.RadioButton
        DailyButton                matlab.ui.control.RadioButton
        StateorRegionLabel         matlab.ui.control.Label
        StateorRegionListBox       matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        cumulative_world; % World object with cumulative covid data
        daily_world; % World object with daily covid data
        cumulative_data; % cumulative covid data
        daily_data % daily covid data
        dates;
        number_of_dates;
        showCases; % plot cases
        showDeaths; % plot deaths
        showBoth; % plot both (cases and deaths)
        showCumulative; % plot cumulative data
        showDaily; % plot daily data
        Averaging
    end
    
    methods (Access = private)
        
        function addCountryNames(app)
            
            [~, number_of_countries] = size(app.cumulative_world.Countries);
            
            countries_names = cell(0);
            countries_names(1, 1) = {'Global'};
            
            for i= 1 : number_of_countries
                countries_names(1, i + 1) = {app.cumulative_world.Countries(i).Name};
            end
            
            app.CountryListBox.Items = countries_names;
        end
        
        function addStateNames(app)
            
            state_names = cell(0);
            state_names(1, 1) = {'All'};
            
            if (~isequal(app.CountryListBox, 'Global'))
                
                index = app.cumulative_world.CountryIndex(app.CountryListBox.Value);
                
                if app.cumulative_world.Countries(index).Number_of_states > 0
                    for i = 1 : app.cumulative_world.Countries(index).Number_of_states
                        state_names(1, i + 1) = {app.cumulative_world.Countries(index).States(i).State_name};
                    end
                end
            end
            
            app.StateorRegionListBox.Items = state_names;
            
        end
        
        function CumulativeToDaily(app)
            app.daily_data = app.cumulative_data;
            
            [rows, columns] = size(app.cumulative_data);
            
            for i = 2 : rows
                
                for j = 4 : columns
                    
                    if app.cumulative_data{i, j}(1) >= app.cumulative_data{i, j - 1}(1)
                        app.daily_data{i, j}(1) = app.cumulative_data{i, j}(1) - app.cumulative_data{i, j - 1}(1);
                    else
                        app.daily_data{i, j}(1) = 0;
                    end
                    
                    if app.cumulative_data{i, j}(2) >= app.cumulative_data{i, j - 1}(2)
                        app.daily_data{i, j}(2) = app.cumulative_data{i, j}(2) - app.cumulative_data{i, j - 1}(2);
                    else
                        app.daily_data{i, j}(2) = 0;
                    end
                    
                end
                
            end
        end
               
        function plotData(app)
            
            % X axis
            startDate = datenum(app.dates(1, :));
            endDate = datenum(app.dates(app.number_of_dates, :));
            xData = linspace(startDate, endDate, app.number_of_dates);
            datetick(app.UIAxes, 'x', 'mm/dd');
            xlim(app.UIAxes, [startDate, endDate]);
            
            cla(app.UIAxes,'reset');
            grid (app.UIAxes, 'on');
            
            if app.showCumulative
                world = app.cumulative_world;
                cumulative_or_daily = "Cumulative";
            elseif app.showDaily
                world = app.daily_world;
                cumulative_or_daily = "Daily";
            end
            
            if isequal(app.CountryListBox.Value, 'Global')
                
                place = world.Entire_world;
                where = "Globally";
            else
                country_index = world.CountryIndex(app.CountryListBox.Value);
                
                if isequal(app.StateorRegionListBox.Value, 'All')
                    place = world.Countries(country_index);
                    where = sprintf("in %s", string(world.Countries(country_index).Name));
                else
                    state_index = world.Countries(country_index).StateIndex(app.StateorRegionListBox.Value);
                    place = world.Countries(country_index).States(state_index);
                    where = sprintf("in %s", string(world.Countries(country_index).States(state_index).State_name));
                end
                
            end
            
            if app.showBoth
                % Cases plot
                yyaxis(app.UIAxes, 'left');
                bar(app.UIAxes, xData, movmean(place.Cases, round(app.Averaging)), 'b');
                
                % Deaths plot
                yyaxis(app.UIAxes, 'right');
                plot(app.UIAxes, xData, movmean(place.Deaths, round(app.Averaging)), 'r');
                
                datetick(app.UIAxes, 'x', 'mm/dd');
                xlim(app.UIAxes, [startDate, endDate]);
                
                cases_or_deaths_or_both = "Cases and Deaths";
            elseif app.showCases
                % Cases plot
                bar(app.UIAxes, xData, movmean(place.Cases, round(app.Averaging)), 'b');
                
                datetick(app.UIAxes, 'x', 'mm/dd');
                xlim(app.UIAxes, [startDate, endDate]);
                
                cases_or_deaths_or_both = "Cases";
            elseif app.showDeaths
                % Deaths plot
                plot(app.UIAxes, xData, movmean(place.Deaths, round(app.Averaging)), 'r');
                datetick(app.UIAxes, 'x', 'mm/dd');
                xlim(app.UIAxes, [startDate, endDate]);
                
                cases_or_deaths_or_both = "Deaths";
            end
            
            % Title
            app.UIAxes.Title.String = sprintf("%s Number Of %s %s", cumulative_or_daily, cases_or_deaths_or_both, where);
        end
        
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            loadData;
            load covid_data.mat covid_data;
            app.cumulative_data = covid_data;
            
            app.cumulative_world = World(app.cumulative_data);
            
            CumulativeToDaily(app);
            app.daily_world = World(app.daily_data);
            
            addCountryNames(app);
            
            app.CountryListBox.Value = 'Global'; % default: plot Global
            addStateNames(app);
            
            % dates variable initialization
            [~, columns ]= size(app.cumulative_data);
            app.dates = char(app.cumulative_data{1, 3:columns});
            [app.number_of_dates, ~] = size(app.dates);
            
            app.showCases = 1; % default: plot cases
            app.showCumulative = 1; % default: plot cumulative
            app.Averaging = 1; % default: average number of days = 1
            
            plotData(app);
        end

        % Selection changed function: DatatoPlotButtonGroup
        function DatatoPlotButtonGroupSelectionChanged(app, event)
            app.showCases = app.CasesButton.Value;
            app.showDeaths = app.DeathsButton.Value;
            app.showBoth = app.BothButton.Value;
            plotData(app);
        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
            addStateNames(app);
            plotData(app);
        end

        % Value changed function: StateorRegionListBox
        function StateorRegionListBoxValueChanged(app, event)
            plotData(app);
        end

        % Selection changed function: OptionButtonGroup
        function OptionButtonGroupSelectionChanged(app, event)
            app.showCumulative = app.CumulativeButton.Value;
            app.showDaily = app.DailyButton.Value;
            plotData(app);
        end

        % Value changing function: AveragedofdaysSlider
        function AveragedofdaysSliderValueChanging(app, event)
            app.Averaging = event.Value;
            plotData(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'COVID-19 Data Vizualization (Data source: Johns Hopkins University)';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            app.CountryListBoxLabel = uilabel(app.UIFigure);
            app.CountryListBoxLabel.Text = 'Country:';
            app.CountryListBox = uilistbox(app.UIFigure);
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.AveragedofdaysSliderLabel = uilabel(app.UIFigure);
            app.AveragedofdaysSliderLabel.Text = 'Averaged # of days';
            app.AveragedofdaysSlider = uislider(app.UIFigure);
            app.AveragedofdaysSlider.Limits = [1 15];
            app.AveragedofdaysSlider.MajorTicks = [1 3 5 7 9 11 13 15];
            app.AveragedofdaysSlider.ValueChangingFcn = createCallbackFcn(app, @AveragedofdaysSliderValueChanging, true);
            app.AveragedofdaysSlider.Value = 1;
            app.DatatoPlotButtonGroup = uibuttongroup(app.UIFigure);
            app.DatatoPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatatoPlotButtonGroupSelectionChanged, true);
            app.DatatoPlotButtonGroup.Title = 'Data to Plot';
            app.CasesButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.CasesButton.Value = true;
            app.DeathsButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.BothButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.BothButton.Text = 'Both';
            app.OptionButtonGroup = uibuttongroup(app.UIFigure);
            app.OptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.OptionButtonGroup.Title = 'Option';
            app.CumulativeButton = uiradiobutton(app.OptionButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionButtonGroup);
            app.DailyButton.Text = 'Daily';
            app.StateorRegionLabel = uilabel(app.UIFigure);
            app.StateorRegionLabel.Text = {'State or '; 'Region:'};
            app.StateorRegionListBox = uilistbox(app.UIFigure);
            app.StateorRegionListBox.ValueChangedFcn = createCallbackFcn(app, @StateorRegionListBoxValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = covid_plotter

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end